classdef DebugApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Line2Gauge_2      matlab.ui.control.LinearGauge
        trainICONexample  matlab.ui.control.Image
        img_line          matlab.ui.control.Image
        TrainMapPanel     matlab.ui.container.Panel
        D21Label          matlab.ui.control.Label
        trainICOND21      matlab.ui.control.Image
        Line2Gauge        matlab.ui.control.LinearGauge
        add1hButton       matlab.ui.control.Button
        add10mButton      matlab.ui.control.Button
        minus10mButton_2  matlab.ui.control.Button
        MapPanel          matlab.ui.container.Panel
        Label_sysTime     matlab.ui.control.Label
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        mapFig; % Description
        gx;
        Line1StartPos=[86,141,43,43];


    end
    
    properties (Access = public)
        trainDispatch;
        displayedTrains;
    end
    
    methods (Access = public)
        
        function display_update_systime(app)
            app.Label_sysTime.Text=app.trainDispatch.SysTimeDisplay;
        end
        
        function updateTrainUI(app)
            timeNow=app.trainDispatch.SysTime;
            for i = 1:length(app.trainDispatch.Trains)
                train=app.trainDispatch.Trains(i);


                if ~isKey(app.displayedTrains, train.trainCode)
                    if strcmp(train.status,'NOTSTARTED')
                        if timeNow>=train.remainingStations(1).departureTime
                            train.trainCode+"发车!!!!"
                            newTrain=uiimage(app.TrainMapPanel);
                            newTrain.ImageSource="assets/trainIcon.png";
                            newTrain.Position = app.Line1StartPos;
                            newTrain.Tag=train.trainCode;
                            app.displayedTrains(train.trainCode)=1;

                        end

                    end

                else
                    
                end
            end

        end
    end
    
    methods (Access = private)
        
        function drawMap(app)
            latshanghaiHQ = 31.19590;
            lonshanghaiHQ = 121.34113;
            
            latnanjingS=32.006161;
            lonnanjingS=118.778572;

            latchangzhouN=31.853495;
            lonchangzhouN=119.955276;

            latsuzhouN=31.421701;
            lonsuzhouN=120.643127;

            lonjiaxingS=120.799957;
            latjiaxingS=30.69058;

            lonliyang=119.4846;
            latliyang=31.41586;

            lonhuzhou=120.023216;
            lathuzhou=30.862961;

            lonhangzhouE=120.212997;
            lathangzhouE=30.29133;

            mapCorx=[latnanjingS latchangzhouN latsuzhouN latshanghaiHQ latjiaxingS lathangzhouE lathuzhou latliyang latnanjingS];
            mapCory=[lonnanjingS lonchangzhouN lonsuzhouN lonshanghaiHQ lonjiaxingS lonhangzhouE lonhuzhou lonliyang lonnanjingS];

            
            geoplot(app.gx,mapCorx, mapCory ,'-pentagram',"LineWidth",1)
            geoplot(app.gx,[30], [120])
            geolimits(app.gx,[30 32.2],[118.4 122])
            %geolimits('auto') 
            geobasemap(app.gx,'topographic')
            geoscatter(app.gx,[30],[120])
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %app.mapFig=uifigure(app.UIFigure);
            %app.gx = geoaxes(app.mapFig);
            %app.gx = geoaxes(app.MapPanel);
            %drawMap(app);
            %app.display_update_systime();
            keySet = {'Jan','Feb','Mar','Apr'};
            valueSet = [327.2 368.2 197.6 178.4];
            app.displayedTrains = containers.Map(keySet,valueSet)

            %app.displayedTrains = containers.Map

            
        end

        % Button pushed function: add10mButton
        function add10mButtonPushed(app, event)
            "加10分钟时间"
            app.trainDispatch.changeSysTime(minutes(10));
            app.updateTrainUI();
        end

        % Button pushed function: add1hButton
        function add1hButtonPushed(app, event)
            "加1小时时间"
            app.trainDispatch.changeSysTime(hours(1));
            app.updateTrainUI();
        end

        % Button pushed function: minus10mButton_2
        function minus10mButton_2Pushed(app, event)
            "减少10分钟"
            app.trainDispatch.changeSysTime(-minutes(10));
            app.updateTrainUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '系统时间';
            app.Label_sysTime = uilabel(app.UIFigure);
            app.Label_sysTime.Text = {'Label_sysTime'; ''};
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.minus10mButton_2 = uibutton(app.UIFigure, 'push');
            app.minus10mButton_2.ButtonPushedFcn = createCallbackFcn(app, @minus10mButton_2Pushed, true);
            app.minus10mButton_2.Text = {'- 10 min'; ''};
            app.add10mButton = uibutton(app.UIFigure, 'push');
            app.add10mButton.ButtonPushedFcn = createCallbackFcn(app, @add10mButtonPushed, true);
            app.add10mButton.Text = {'+ 10 min'; ''};
            app.add1hButton = uibutton(app.UIFigure, 'push');
            app.add1hButton.ButtonPushedFcn = createCallbackFcn(app, @add1hButtonPushed, true);
            app.add1hButton.Text = {'+ 1 hour'; ''};
            app.TrainMapPanel = uipanel(app.UIFigure);
            app.TrainMapPanel.Title = 'TrainMap';
            app.Line2Gauge = uigauge(app.TrainMapPanel, 'linear');
            app.Line2Gauge.Limits = [1 4];
            app.Line2Gauge.MajorTicks = [1 2 3 4];
            app.Line2Gauge.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.trainICOND21 = uiimage(app.TrainMapPanel);
            app.trainICOND21.ImageSource = 'trainIcon.png';
            app.D21Label = uilabel(app.TrainMapPanel);
            app.D21Label.Text = 'D21';
            app.img_line = uiimage(app.UIFigure);
            app.img_line.ImageSource = 'lines_visual.png';
            app.trainICONexample = uiimage(app.UIFigure);
            app.trainICONexample.Visible = 'off';
            app.trainICONexample.ImageSource = 'trainIcon.png';
            app.Line2Gauge_2 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_2.Limits = [1 4];
            app.Line2Gauge_2.MajorTicks = [1 2 3 4];
            app.Line2Gauge_2.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DebugApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end